classdef siscan < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        AcquisitionTab            matlab.ui.container.Tab
        SINGLESHOTDSCANLabel      matlab.ui.control.Label
        InitializeButton          matlab.ui.control.Button
        SingleImageButton         matlab.ui.control.Button
        StopButton                matlab.ui.control.Button
        ExposuremsSpinnerLabel    matlab.ui.control.Label
        ExposuremsSpinner         matlab.ui.control.Spinner
        UIAxes                    matlab.ui.control.UIAxes
        FramerateSpinnerLabel     matlab.ui.control.Label
        FramerateSpinner          matlab.ui.control.Spinner
        ColormapDropDownLabel     matlab.ui.control.Label
        ColormapDropDown          matlab.ui.control.DropDown
        CONTROLSLabel             matlab.ui.control.Label
        INDICATORSLabel           matlab.ui.control.Label
        ExposuremsEditFieldLabel  matlab.ui.control.Label
        ExposuremsEditField       matlab.ui.control.NumericEditField
        LiveViewButton            matlab.ui.control.Button
        TreatmentTab              matlab.ui.container.Tab
        RetrievalTab              matlab.ui.container.Tab
    end


    properties (Access = private)
        
        IDScam; % Camera class
        image % image class
    end


    methods (Access = private)

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)

                app.InitializeButton.FontColor = 'green';
                app.IDScam = IDSCamera('4103393584');
                app.IDScam.init();
                app.ExposuremsSpinner.Enable = 'on';
        end

        % Button pushed function: SingleImageButton
        function SingleImageButtonPushed(app, event)
                app.IDScam.getImageAsyncStartCam();
                app.image = app.IDScam.getImageAsyncEndCam();
                imshow(uint8(app.image/16),'Parent',app.UIAxes)
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.IDScam.delete();
            app.InitializeButton.FontColor = 'k';
            
        end

        % Value changed function: ExposuremsSpinner
        function ExposuremsSpinnerValueChanged(app, event)
            value = app.ExposuremsSpinner.Value;
            app.IDScam.setExposure(value*1e-3)
            fprintf('Exposure set to %.1f ms.\n',value)
            expTime = getExposure(app.IDScam);
            app.ExposuremsEditField.Value = expTime*1e3;
        end

        % Button pushed function: LiveViewButton
        function LiveViewButtonPushed(app, event)
            app.image = app.IDScam.startLiveView();
            imshow(uint12(app.image),'Parent',app.UIAxes)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AcquisitionTab = uitab(app.TabGroup);
            app.AcquisitionTab.Title = 'Acquisition';
            app.SINGLESHOTDSCANLabel = uilabel(app.AcquisitionTab);
            app.SINGLESHOTDSCANLabel.Text = 'SINGLE-SHOT DSCAN';
            app.InitializeButton = uibutton(app.AcquisitionTab, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Text = 'Initialize';
            app.SingleImageButton = uibutton(app.AcquisitionTab, 'push');
            app.SingleImageButton.ButtonPushedFcn = createCallbackFcn(app, @SingleImageButtonPushed, true);
            app.SingleImageButton.Text = 'Single Image';
            app.StopButton = uibutton(app.AcquisitionTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ExposuremsSpinnerLabel = uilabel(app.AcquisitionTab);
            app.ExposuremsSpinnerLabel.Text = 'Exposure, ms';
            app.ExposuremsSpinner = uispinner(app.AcquisitionTab);
            app.ExposuremsSpinner.ValueChangedFcn = createCallbackFcn(app, @ExposuremsSpinnerValueChanged, true);
            app.UIAxes = uiaxes(app.AcquisitionTab);
            title(app.UIAxes, 'Scan')
            app.FramerateSpinnerLabel = uilabel(app.AcquisitionTab);
            app.FramerateSpinnerLabel.Text = 'Framerate';
            app.FramerateSpinner = uispinner(app.AcquisitionTab);
            app.ColormapDropDownLabel = uilabel(app.AcquisitionTab);
            app.ColormapDropDownLabel.Text = 'Colormap';
            app.ColormapDropDown = uidropdown(app.AcquisitionTab);
            app.ColormapDropDown.Items = {'Monochrome', 'Jet', 'Other', 'Option 4'};
            app.ColormapDropDown.Value = 'Monochrome';
            app.CONTROLSLabel = uilabel(app.AcquisitionTab);
            app.CONTROLSLabel.Text = 'CONTROLS';
            app.INDICATORSLabel = uilabel(app.AcquisitionTab);
            app.INDICATORSLabel.Text = 'INDICATORS';
            app.ExposuremsEditFieldLabel = uilabel(app.AcquisitionTab);
            app.ExposuremsEditFieldLabel.Text = 'Exposure, ms';
            app.ExposuremsEditField = uieditfield(app.AcquisitionTab, 'numeric');
            app.ExposuremsEditField.Editable = 'off';
            app.LiveViewButton = uibutton(app.AcquisitionTab, 'push');
            app.LiveViewButton.ButtonPushedFcn = createCallbackFcn(app, @LiveViewButtonPushed, true);
            app.LiveViewButton.Text = 'Live View';
            app.TreatmentTab = uitab(app.TabGroup);
            app.TreatmentTab.Title = 'Treatment';
            app.RetrievalTab = uitab(app.TabGroup);
            app.RetrievalTab.Title = 'Retrieval';
        end
    end

    methods (Access = public)

        % Construct app
        function app = siscan

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end